classdef seqtool_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PGEToolboxGUI                  matlab.ui.Figure
        File                           matlab.ui.container.Menu
        OpenDataFile                   matlab.ui.container.Menu
        CloseDataFile                  matlab.ui.container.Menu
        SaveExportDataAs               matlab.ui.container.Menu
        SaveFASTAFile                  matlab.ui.container.Menu
        SavePhylipFile                 matlab.ui.container.Menu
        SavePAMLFile                   matlab.ui.container.Menu
        SaveMatFile                    matlab.ui.container.Menu
        AssignSequencesInWorkspace     matlab.ui.container.Menu
        SequenceTextEditor             matlab.ui.container.Menu
        GenerateRandomSequences        matlab.ui.container.Menu
        Exit                           matlab.ui.container.Menu
        Data                           matlab.ui.container.Menu
        ViewSequences                  matlab.ui.container.Menu
        ViewSequencesInfo              matlab.ui.container.Menu
        AssignPopulation               matlab.ui.container.Menu
        AssignLocus                    matlab.ui.container.Menu
        RemoveGapsInSequences          matlab.ui.container.Menu
        WorkOnPloymorphicSites         matlab.ui.container.Menu
        WorkOnParsimonyInformativeSites  matlab.ui.container.Menu
        IncludeExcludeSequences        matlab.ui.container.Menu
        IncludeExcludeSites            matlab.ui.container.Menu
        RestoreSequences               matlab.ui.container.Menu
        Analysis                       matlab.ui.container.Menu
        ReportPolymorphicSites         matlab.ui.container.Menu
        NucleotideDiversity            matlab.ui.container.Menu
        EstimateTheta                  matlab.ui.container.Menu
        SiteFreqSpectrum               matlab.ui.container.Menu
        FoldedSiteFrequencySpectrum    matlab.ui.container.Menu
        UnfoldedSiteFrequencySpectrum  matlab.ui.container.Menu
        Tajima89Test                   matlab.ui.container.Menu
        FuLi93Tests                    matlab.ui.container.Menu
        FayWusTestH                    matlab.ui.container.Menu
        SlidingWindowsAnalysis         matlab.ui.container.Menu
        SlidingWinTajimaD              matlab.ui.container.Menu
        SlidingWinFuDStar              matlab.ui.container.Menu
        SlidingWinFuFs                 matlab.ui.container.Menu
        HaplotypeDiversity             matlab.ui.container.Menu
        Watterson78F                   matlab.ui.container.Menu
        FuFsTest                       matlab.ui.container.Menu
        R2Test                         matlab.ui.container.Menu
        McDKTestCMD                    matlab.ui.container.Menu
        McDKTestGUI                    matlab.ui.container.Menu
        HKATest                        matlab.ui.container.Menu
        Recombination                  matlab.ui.container.Menu
        FourGameteTest                 matlab.ui.container.Menu
        LinkageDisequilibrium          matlab.ui.container.Menu
        LDOverview                     matlab.ui.container.Menu
        LDDistancePlot                 matlab.ui.container.Menu
        LDDistancePlotD                matlab.ui.container.Menu
        LDDistancePlotDAbs             matlab.ui.container.Menu
        LDDistancePlotDPrime           matlab.ui.container.Menu
        LDDistancePlotDPrimeAbs        matlab.ui.container.Menu
        LDDistancePlotR                matlab.ui.container.Menu
        LDDistancePlotR2               matlab.ui.container.Menu
        LDMatrixPlot                   matlab.ui.container.Menu
        LDMatrixPlotD                  matlab.ui.container.Menu
        LDMatrixPlotDAbs               matlab.ui.container.Menu
        LDMatrixPlotDPrime             matlab.ui.container.Menu
        LDMatrixPlotDPrimeAbs          matlab.ui.container.Menu
        LDMatrixPlotR                  matlab.ui.container.Menu
        LDMatrixPlotR2                 matlab.ui.container.Menu
        WallBQ                         matlab.ui.container.Menu
        Tools                          matlab.ui.container.Menu
        CoalescentSimulations          matlab.ui.container.Menu
        TestOfIndependence2x2Table     matlab.ui.container.Menu
        RatioOfAdaptiveNonsynSub       matlab.ui.container.Menu
        FayWycoffWu01                  matlab.ui.container.Menu
        SmithEyreWalker02              matlab.ui.container.Menu
        RatioOfAdaptiveNonsynSubFWW    matlab.ui.container.Menu
        RatioOfAdaptiveNonsynSubSEW    matlab.ui.container.Menu
        MKRPFTest                      matlab.ui.container.Menu
        MKRPFTestWriteInputLine        matlab.ui.container.Menu
        MKRPFTestExampleInput          matlab.ui.container.Menu
        SNPTool                        matlab.ui.container.Menu
        Help                           matlab.ui.container.Menu
        HelpContents                   matlab.ui.container.Menu
        Demos                          matlab.ui.container.Menu
        HelpWebSite                    matlab.ui.container.Menu
        CheckLatestVersion             matlab.ui.container.Menu
        Citation                       matlab.ui.container.Menu
        AboutPGEToolbox                matlab.ui.container.Menu
    end

    
    properties (Access = private)
        aln % Description
        aln_ori
    end
    
    methods (Access = private)
        function SetMenuStatus(app, handles)
            %  app.aln_ori
            
            %set(app.McDKTest,'enable','off');
            set(app.HKATest,'enable','off');
            set(app.MKRPFTest,'enable','off');
            
            proteinonly=[];
            nucleotideonly=[];
            
            alltags=[app.AboutPGEToolbox,...
            app.Analysis,...
            app.AssignLocus,...
            app.AssignPopulation,...
            app.AssignSequencesInWorkspace,...
            app.CheckLatestVersion,...
            app.Citation,...
            app.CloseDataFile,...
            app.CoalescentSimulations,...
            app.Data,...
            app.Demos,...
            app.EstimateTheta,...
            app.Exit,...
            app.FayWusTestH,...
            app.FayWycoffWu01,...
            app.File,...
            app.FoldedSiteFrequencySpectrum,...
            app.FourGameteTest,...
            app.FuFsTest,...
            app.FuLi93Tests,...
            app.GenerateRandomSequences,...
            app.HaplotypeDiversity,...
            app.Help,...
            app.HelpContents,...
            app.HelpWebSite,...
            app.IncludeExcludeSequences,...
            app.IncludeExcludeSites,...
            app.LDDistancePlot,...
            app.LDDistancePlotD,...
            app.LDDistancePlotDAbs,...
            app.LDDistancePlotDPrime,...
            app.LDDistancePlotDPrimeAbs,...
            app.LDDistancePlotR,...
            app.LDDistancePlotR2,...
            app.LDMatrixPlot,...
            app.LDMatrixPlotD,...
            app.LDMatrixPlotDAbs,...
            app.LDMatrixPlotDPrime,...
            app.LDMatrixPlotDPrimeAbs,...
            app.LDMatrixPlotR,...
            app.LDMatrixPlotR2,...
            app.LDOverview,...
            app.LinkageDisequilibrium,...
            app.McDKTestCMD,...
            app.McDKTestGUI,...
            app.MKRPFTest,...
            app.MKRPFTestExampleInput,...
            app.MKRPFTestWriteInputLine,...
            app.NucleotideDiversity,...
            app.OpenDataFile,...
            app.R2Test,...
            app.RatioOfAdaptiveNonsynSub,...
            app.RatioOfAdaptiveNonsynSubFWW,...
            app.RatioOfAdaptiveNonsynSubSEW,...
            app.Recombination,...
            app.RemoveGapsInSequences,...
            app.ReportPolymorphicSites,...
            app.RestoreSequences,...
            app.SaveExportDataAs,...
            app.SaveFASTAFile,...
            app.SaveMatFile,...
            app.SavePhylipFile,...
            app.SequenceTextEditor,...
            app.SiteFreqSpectrum,...
            app.SlidingWindowsAnalysis,...
            app.SlidingWinFuDStar,...
            app.SlidingWinFuFs,...
            app.SlidingWinTajimaD,...
            app.SmithEyreWalker02,...
            app.SNPTool,...
            app.Tajima89Test,...
            app.TestOfIndependence2x2Table,...
            app.Tools,...
            app.UnfoldedSiteFrequencySpectrum,...
            app.ViewSequences,...
            app.ViewSequencesInfo,...
            app.WallBQ,...
            app.Watterson78F,...
            app.WorkOnParsimonyInformativeSites,...
            app.WorkOnPloymorphicSites];


            cannotemptyaln = [
                app.SaveExportDataAs,...
                app.CloseDataFile,...
                app.MKRPFTestWriteInputLine,...
                app.FayWycoffWu01,...
                app.SmithEyreWalker02,...
                app.ViewSequences,...
                app.ViewSequencesInfo,...
                app.AssignLocus,...
                app.AssignPopulation,...
                app.RestoreSequences,...
                app.RemoveGapsInSequences,...
                app.WorkOnParsimonyInformativeSites,...
                app.WorkOnPloymorphicSites,...
                app.IncludeExcludeSequences,...
                app.IncludeExcludeSites,...
                app.ReportPolymorphicSites,...
                app.NucleotideDiversity,...
                app.EstimateTheta,...
                app.SiteFreqSpectrum,...
                app.Tajima89Test,...
                app.FayWusTestH,...
                app.FuLi93Tests,...
                app.SlidingWindowsAnalysis,...
                app.HaplotypeDiversity,...
                app.Watterson78F,...
                app.FuFsTest,...
                app.R2Test,...
                app.McDKTestCMD,...
                app.McDKTestGUI,...
                app.Recombination,...
                app.LinkageDisequilibrium,...
                app.WallBQ];
            
            if (isempty(app.app.aln))
                set(cannotemptyaln,'enable','off');
            else
               if (hasgap(app.aln))
                    set(cannotemptyaln,'enable','on');
                    set(app.Analysis,'enable','off');
                    set(app.FayWycoffWu01,'enable','off');
                    set(app.SmithEyreWalker02,'enable','off');
               else
                    set(cannotemptyaln,'enable','on');
               end
            
               if ~(isempty(app.aln_ori))
                    set(app.RestoreSequences,'enable','on');
                else
                    set(app.RestoreSequences,'enable','off');
               end
            end
        end
        
        function [winsiz, stpsiz] = i_getwindowsizestep(app)
               prompt={'Window size:','Step size:'};
               def={'30','10'};
               dlgTitle='Window Size and Step Size';
               lineNo=1;
               answer=inputdlg(prompt,dlgTitle,lineNo,def);
            
             winsiz=0; stpsiz=0;
             if ~(isempty(answer)),
               winsiz=str2num(answer{1});
               stpsiz=str2num(answer{2});
             end
        end
        
        function i_try2dolinkdisequdistance(app, txt, outn)
            try
               warning off MATLAB:polyfit:PolyNotUnique
               outputmatrix=0; warninglarge=1;
               [D_raw,D_prime,R2,R,D_dist,siteidx,done] = linkdisequ(app.aln,outputmatrix,warninglarge);
               warning on MATLAB:polyfit:PolyNotUnique
               if ~(done), return; end
            catch
                warndlg('No pairwise comparisons.')
                warning on MATLAB:polyfit:PolyNotUnique
                return;
            end
            switch (outn)
                case (1)
                     Data=D_raw;
                case (3)
                     Data=D_prime;
                case (6)
                     Data=R2;
                case (2)
                    Data=abs(D_raw);
                case (4)
                    Data=abs(D_prime);
                case (5)
                    Data=R;
            end
            
            	figure;
            	plot(D_dist, Data, '.')
            	xlabel('Nucleotide distance')
            	ylabel(txt)
        end
        
        function i_try2dolinkdisequmatrix(app, txt, outn)
            
            try
               warning off MATLAB:polyfit:PolyNotUnique
               outputmatrix=1; warninglarge=1;
               [D_raw,D_prime,R2,R,D_dist,siteidx,done] = linkdisequ(app.aln,outputmatrix,warninglarge);
               warning on MATLAB:polyfit:PolyNotUnique
               if ~(done), return; end
            catch
                warndlg('No pairwise comparisons.')
                warning on MATLAB:polyfit:PolyNotUnique
                return;
            end
            switch (outn)
                case (1)
                     Data=D_raw;
                case (3)
                     Data=D_prime;
                case (6)
                     Data=R2;
                case (2)
                    Data=abs(D_raw);
                case (4)
                    Data=abs(D_prime);
                case (5)
                    Data=R;
            end
            
            	figure;
            	imagesc(Data); axis equal;
            	colormap(copper);
            	colorbar; xlabel(txt); ylabel(txt);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function seqtool_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to seqtool (see VARARGIN)
%             
%             % Choose default command line output for seqtool
%             global pgeguiolddir;
%             app.output = hObject;
%             
%             scrsz=get(0,'ScreenSize');
%             pos_act=get(gcf,'Position');
%             xr=scrsz(3)-pos_act(3);
%             xp=round(xr/2);
%             yr=scrsz(4)-pos_act(4);
%             yp=round(yr/2);
%             set(gcf,'position',[xp yp pos_act(3) pos_act(4)]);
%             
%             if ~ispref('PGEToolbox','lastworkingdir')
%                addpref('PGEToolbox','lastworkingdir',pwd)
%             else
%                try
%                 cd(getpref('PGEToolbox','lastworkingdir',pwd));
%                catch ME
%                 rmpref('PGEToolbox','lastworkingdir');
%                end
%             
%             end
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             % UIWAIT makes seqtool wait for user response (see UIRESUME)
%             % uiwait(app.PGEToolboxGUI);
%             % load 'seqtool.mat' PGEGUI_SaveDistance
%             %dirstr = fileparts(which(mfilename));
%             %cd(dirstr);
%             pgeguiolddir=pwd;
%             cdpge;
%             %warning off all
%             % deletetempfiles;
%             %clear  app.aln_ori
%             SetMenuStatus(handles);
movegui(app.PGEToolboxGUI,'center');
        end

        % Menu selected function: AboutPGEToolbox
        function AboutPGEToolbox_Callback(app, event)
            % hObject    handle to AboutPGEToolbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global pgeversionstr
            info{1}='';
            info{2}='PGEToolbox - Population Genetics & Evolution Toolbox';
            info{3}='';
            info{4}=['Version ',pgeversionstr];
            info{5}='';
            info{6}='Author: James Cai';
            info{7}='';
            info{8}='Copyright 2010-2013  All Rights Reserved';
            info{9}='';
            info{10}='Please reference this software when using as part of';
            info{11}='research.';
            info{12} ='';
            helpdlg(info,'About');
        end

        % Menu selected function: AssignLocus
        function AssignLocus_Callback(app, event)
            % hObject    handle to AssignLoci (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
           
            try
            [locus2,v] = choosebox('Name','Assign Locus','PromptString',...
            'Sequences from locus 1:','SelectString','Sequences from locus 2:',...
            'ListString',app.aln.seqnames);
            
            if (v~=1),
            	return;
            else
                app.aln.locus(locus2)=max(app.aln.locus)+1;
            end
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: AssignPopulation
        function AssignPopulation_Callback(app, event)
            % hObject    handle to AssignPopulations (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
            [taxset2,v] = choosebox('Name','Interspecies Sequences','PromptString',...
            'Sequences from species 1:','SelectString','Sequences from species 2:',...
            'ListString',app.aln.seqnames);
            if (v~=1),
            	return;
            else
                app.aln.population(taxset2)=max(app.aln.population)+1;
            end
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: AssignSequencesInWorkspace
        function AssignSequencesInWorkspace_Callback(app, event)
            % hObject    handle to AssignSequencesInWorkspace (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
                %{
                if ~isempty(app.aln),
                assignin('base','app.aln',app.aln);
                %disp('Sequences have been assigned as the variable, app.aln, in workspace.')
                helpdlg('ALN assigned.','Assign varibles into workspace')
                else
                warndlg('Sequences is empty.','Assign varibles into workspace')
                end
                %}
                export2wsdlg({'Save alignment (ALN) to variable named:'},...
                             {'app.aln'},{app.aln},...
                             'Export to Workspace');
            catch ME
              errordlg(ME)
            end
        end

        % Menu selected function: CheckLatestVersion
        function CheckLatestVersion_Callback(app, event)
            % hObject    handle to CheckLatestVersion (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global pgeversionstr
            
            try
            	latestver = urlread('http://www.bioinformatics.org/pgetoolbox/LatestVersionTag');
            catch
            	warndlg('Can''t read from URL.');
            	return;
            end
            
            if (strcmp(latestver,pgeversionstr)==1),
                helpdlg('You are running the latest version.','No update available')
            else
            	ButtonName=questdlg(sprintf(['Download latest version %s?'], latestver), ...
            			    'Check Latest Version', ...
            			    'Go to website','Cancel','Go to website');
            	switch ButtonName,
            	    case 'Go to website',
                       web('http://bioinformatics.org/pgetoolbox/','-browser')
            	    otherwise
            		return;
            	end
            end
        end

        % Menu selected function: Citation
        function Citation_Callback(app, event)
            % hObject    handle to Citation (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            disp(' ')
            disp('Cai JJ')
            disp('PGEToolbox: a MATLAB toolbox for population genetics and evolution.')
            disp('http://bioinformatics.org/pgetoolbox/')
            disp(' ')
        end

        % Menu selected function: CloseDataFile
        function CloseDataFile_Callback(app, event)
            % hObject    handle to RemoveAln (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.aln=[];
            clear 
            SetMenuStatus(handles);
        end

        % Menu selected function: CoalescentSimulations
        function CoalescentSimulations_Callback(app, event)
            % hObject    handle to CoalescentSimulations (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            coalsimdlg
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: Demos
        function Demos_Callback(app, event)
            % hObject    handle to Demo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            demo pgetoolbox
        end

        % Menu selected function: EstimateTheta
        function EstimateTheta_Callback(app, event)
            % hObject    handle to EstimateTheta (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
               estimatetheta(app.aln);
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: Exit
        function Exit_Callback(app, event)
            % hObject    handle to Exit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             global pgeguiolddir app.aln
%             app.aln=[];
%             cd(pgeguiolddir);
%             clear  olddir pgeversionstr
%             warning on all
            close;
        end

        % Menu selected function: FayWusTestH
        function FayWusTestH_Callback(app, event)
            % hObject    handle to FayWusTestH (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
                  str=app.aln.seqnames;
                  [s,v] = listdlg('PromptString','Select an ancestral reference:',...
                                  'SelectionMode','single',...
                                  'ListString',str);
            if v>0
                fprintf('NOTE: Sequence %d, ''%s'', was used as ancestral\nreference to count external-mutations.\n',...
                    s,deblank(str{s}));
                try
                    seq=app.aln.seq;
                    n=size(seq,1);
                    idx=setdiff([1:n],s);
                    ancseq=seq(s,:);
                    seq=seq(idx,:);
                    faywu00h_test(seq,ancseq);
            
                catch ME
                errordlg(ME)
                end
            end
        end

        % Menu selected function: FayWycoffWu01
        function FayWycoffWu01_Callback(app, event)
            % hObject    handle to FayWycoffWu01 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
            [~,data]=line4sewfww(app.aln);
            D=sewfww_sampledata(data);
            if isempty(D)
                return;
            else
                [~,fww] = sewfww(D);
                i_dispheader('alpha, Fay, Wycoff and Wu (2001)')
                fprintf('alpha=%.3f\n',fww);
                i_dispfooter
            end
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: FoldedSiteFrequencySpectrum
        function FoldedSiteFrequencySpectrum_Callback(app, event)
            % hObject    handle to FoldedSiteFrequencySpectrum (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
                sfs(app.aln);
                f=sfs(app.aln);
                figure;
                bar(f)
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: FourGameteTest
        function FourGameteTest_Callback(app, event)
            % hObject    handle to MinimumNumOfRecombinationEvents (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
                seq=app.aln.seq;
                hudsonkaplan85rm(seq);
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: FuFsTest
        function FuFsTest_Callback(app, event)
            % hObject    handle to FuFsTest (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ;
            try
                fu97fs(app.aln);
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: FuLi93Tests
        function FuLi93Tests_Callback(app, event)
            % hObject    handle to FuLi93DS (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
            fuli93dsfs_test(app.aln);
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: GenerateRandomSequences
        function GenerateRandomSequences_Callback(app, event)
            % hObject    handle to GenerateRandomSequences (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
               prompt={'Number of Sequences:','Length of Sequences:'};
               def={'10','300'};
               dlgTitle='Generate Random Sequences';
               lineNo=1;
               answer=inputdlg(prompt,dlgTitle,lineNo,def);
            
             if ~(isempty(answer)),
                  n=str2num(answer{1});
                  m=str2num(answer{2});
                  try
                      sroot=randseq(1,m);
                      s=repmat(sroot,n,1);
            	  seedms_mex;
                      [segseq,segsite]=ms_mex(n,0,round(m/2));
                      segsite=ceil(segsite*m);
                      alle2=randseq(1,m);
                      for k=1:size(segseq,2)
                        colk=uint8(segseq(:,k));
                        colk(colk==1)=alle2(k);
                        colk(colk==0)=sroot(k);
                        s(:,segsite(k))=colk;
                      end
                  catch exception
                      disp(exception.identifier);
                      s=randseq(n,m);
                  end
                  names={};
            
               for k=1:n,
                   names{k}=['seq',sprintf('%d',k)];
               end
            
            
               app.aln.seqtype = 1;
               %app.aln.geneticcode = geneticcode;
               app.aln.seqnames = names;
               app.aln.seq=s;
               	app.aln.locus=ones(n,1);
            	app.aln.population=ones(n,1);
            	app.aln.count=ones(n,1);
               SetMenuStatus(handles);
               app.aln
            end
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: HaplotypeDiversity
        function HaplotypeDiversity_Callback(app, event)
            % hObject    handle to HaplotypeDiversity (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
                hapdiv_test(app.aln.seq);
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: HelpContents
        function HelpContents_Callback(app, event)
            % hObject    handle to HelpContents (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            helpwin pgetoolbox;
        end

        % Menu selected function: HelpWebSite
        function HelpWebSite_Callback(app, event)
            % hObject    handle to HelpWebSite (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                web('http://bioinformatics.org/pgetoolbox/','-browser')
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: IncludeExcludeSequences
        function IncludeExcludeSequences_Callback(app, event)
            % hObject    handle to IncludeExcludeSequences (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
             app.aln_ori
            try
            [s,v] = choosebox('Name','Pick outgroup sequence(s)','PromptString','Sequences available:',...
                'SelectString','Selected sequences:','ListString',app.aln.seqnames);
            if (v==1)
                app.aln_ori=app.aln;
                app.aln.seq = app.aln.seq(s,:);
                app.aln.seqnames=app.aln.seqnames(s);
                SetMenuStatus(handles);
            else
                return;
            end
            end
        end

        % Menu selected function: IncludeExcludeSites
        function IncludeExcludeSites_Callback(app, event)
            % hObject    handle to IncludeExcludeSites (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
             app.aln_ori
               prompt={'From site:','to:'};
               m=length(app.aln.seq);
               def={'1',num2str(m)};
               dlgTitle='Region to Analyse';
               lineNo=1;
               answer=inputdlg(prompt,dlgTitle,lineNo,def);
            
             if ~(isempty(answer)),
                  nstart=str2num(answer{1});
                  nend=str2num(answer{2});
               if ~(nstart==1 && nend==m),
                app.aln_ori=app.aln;
                app.aln.seq=app.aln.seq(:,[nstart:nend]);
                app.aln.pos=app.aln.pos([nstart:nend]);
                viewseq(app.aln);
                SetMenuStatus(handles);
               end
            end
        end

        % Menu selected function: LDDistancePlotDAbs
        function LDDistancePlotDAbs_Callback(app, event)
            % hObject    handle to LDDistancePlotDAbs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            i_try2dolinkdisequdistance(app.aln,'|D|',2);
        end

        % Menu selected function: LDDistancePlotDPrimeAbs
        function LDDistancePlotDPrimeAbs_Callback(app, event)
            % hObject    handle to LDDistancePlotDPrimeAbs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            i_try2dolinkdisequdistance(app.aln,'|D''|',4);
        end

        % Menu selected function: LDDistancePlotDPrime
        function LDDistancePlotDPrime_Callback(app, event)
            % hObject    handle to LDDistancePlotDPrime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            i_try2dolinkdisequdistance(app.aln,'D''',2);
        end

        % Menu selected function: LDDistancePlotD
        function LDDistancePlotD_Callback(app, event)
            % hObject    handle to LDDistancePlotD (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            i_try2dolinkdisequdistance(app.aln,'D',1);
        end

        % Menu selected function: LDDistancePlotR2
        function LDDistancePlotR2_Callback(app, event)
            % hObject    handle to LDDistancePlotR2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            i_try2dolinkdisequdistance(app.aln,'R^2',6);
        end

        % Menu selected function: LDDistancePlotR
        function LDDistancePlotR_Callback(app, event)
            % hObject    handle to LDDistancePlotR (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            i_try2dolinkdisequdistance(app.aln,'R',5);
        end

        % Menu selected function: LDMatrixPlotDAbs
        function LDMatrixPlotDAbs_Callback(app, event)
            % hObject    handle to LDMatrixPlotDAbs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            i_try2dolinkdisequmatrix(app.aln,'|D|',2);
        end

        % Menu selected function: LDMatrixPlotDPrimeAbs
        function LDMatrixPlotDPrimeAbs_Callback(app, event)
            % hObject    handle to LDMatrixPlotDPrimeAbs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            i_try2dolinkdisequmatrix(app.aln,'|D''|',4);
        end

        % Menu selected function: LDMatrixPlotDPrime
        function LDMatrixPlotDPrime_Callback(app, event)
            % hObject    handle to LDMatrixPlotDPrime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            i_try2dolinkdisequmatrix(app.aln,'D''',3);
        end

        % Menu selected function: LDMatrixPlotD
        function LDMatrixPlotD_Callback(app, event)
            % hObject    handle to LDMatrixPlotD (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            outputmatrix=1;
            i_try2dolinkdisequmatrix(app.aln,'D',1);
        end

        % Menu selected function: LDMatrixPlotR2
        function LDMatrixPlotR2_Callback(app, event)
            % hObject    handle to LDMatrixPlotR2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            i_try2dolinkdisequmatrix(app.aln,'R^2',6);
        end

        % Menu selected function: LDMatrixPlotR
        function LDMatrixPlotR_Callback(app, event)
            % hObject    handle to LDMatrixPlotR (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            i_try2dolinkdisequmatrix(app.aln,'R',5);
        end

        % Menu selected function: LDOverview
        function LDOverview_Callback(app, event)
            % hObject    handle to LDOverview (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
                warning off MATLAB:polyfit:PolyNotUnique
                linkdisequ(app.aln,0,1);
            catch
                warndlg('No pairwise comparisons.')
            end
            warning on MATLAB:polyfit:PolyNotUnique
        end

        % Menu selected function: McDKTestCMD
        function McDKTestCMD_Callback(app, event)
            % hObject    handle to McDKTest (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
                mktestcmd(app.aln);
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: McDKTestGUI
        function McDKTestGUI_Callback(app, event)
            % hObject    handle to DPRSTable (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
                mktestgui(app.aln)
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: NucleotideDiversity
        function NucleotideDiversity_Callback(app, event)
            % hObject    handle to NucleotideDiversity (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
                nucdiv(app.aln);
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: OpenDataFile
        function OpenDataFile_Callback(app, event)
            % hObject    handle to OpenFASTAFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
             app.aln_ori
            aln2=pge_openfile;
            if ~(isempty(aln2)),
                app.aln=aln2;
                if (hasgap(app.aln))
                    aswer=questdlg('Do you want to remove gaps (completely delete)?');
                 switch aswer,
                 case 'Yes',
                       app.aln_ori=app.aln;
                       app.aln=rmgaps(app.aln);
                 case 'No',
            
                 case 'Cancel',
                      app.aln=[];
                  otherwise
                      app.aln=[];
                 end
                end
                SetMenuStatus(handles);
            else
                return;
            end
        end

        % Menu selected function: R2Test
        function R2Test_Callback(app, event)
            % hObject    handle to R2Test (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
                r2_test(app.aln);
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: RatioOfAdaptiveNonsynSubFWW
        function RatioOfAdaptiveNonsynSubFWW_Callback(app, event)
            % hObject    handle to RatioOfAdaptiveNonsynSubFWW (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            D=sewfww_sampledata;
            if isempty(D)
                return;
            else
                try
                [~,fww] = sewfww(D);
                i_dispheader('alpha, Fay, Wycoff and Wu (2001)')
                fprintf('alpha=%.3f\n',fww);
                i_dispfooter
            catch ME
                errordlg(ME.message)
            end
            end
        end

        % Menu selected function: RatioOfAdaptiveNonsynSubSEW
        function RatioOfAdaptiveNonsynSubSEW_Callback(app, event)
            % hObject    handle to RatioOfAdaptiveNonsynSubSEW (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            D=sewfww_sampledata;
            if isempty(D)
                return;
            else
            try
                [sew] = sewfww(D);
                i_dispheader('alpha, Smith and Eyre-Walker (2002)')
                fprintf('alpha=%.3f\n',sew);
                i_dispfooter
            catch ME
                errordlg(ME.message)
            end
            end
        end

        % Menu selected function: RemoveGapsInSequences
        function RemoveGapsInSequences_Callback(app, event)
            % hObject    handle to RemoveGapsInSequences (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
             app.aln_ori
            try
            if (hasGap(app.aln))
                app.aln_ori=app.aln;
                app.aln=rmgaps(app.aln); viewseq(app.aln);
                SetMenuStatus(handles);
            else
                return;
            end
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: ReportPolymorphicSites
        function ReportPolymorphicSites_Callback(app, event)
            % hObject    handle to ReportPolymorphicSites (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
               reportpolysites(app.aln);
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: RestoreSequences
        function RestoreSequences_Callback(app, event)
            % hObject    handle to RestoreSequences (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
             app.aln_ori
            try
            app.aln=app.aln_ori;
            disp('Sequences restored!');
            app.aln_ori=[];
            SetMenuStatus(handles);
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: SNPTool
        function SNPTool_Callback(app, event)
            % hObject    handle to SNPTool (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            snptool
        end

        % Menu selected function: SaveFASTAFile
        function SaveFASTAFile_Callback(app, event)
            % hObject    handle to SaveFASTAFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            writefasta(app.aln);
        end

        % Menu selected function: SaveMatFile
        function SaveMatFile_Callback(app, event)
            % hObject    handle to SaveMatFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
                [filename, pathname, filterindex] = uiputfile( ...
                   {'*.mat', 'MAT-file (*.mat)';
                    '*.*',  'All Files (*.*)'}, ...
                    'Save as');
            	if ~(filename), return; end
            	filename=[pathname,filename];
            
            	if (filterindex==1)
            	if (isempty(find(filename=='.', 1)))
            		filename=[filename,'.mat'];
            	end
            	end
            save(filename,'app.aln');
        end

        % Menu selected function: SavePhylipFile
        function SavePhylipFile_Callback(app, event)
            % hObject    handle to SavePhylipFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            writephylip_i(app.aln);
        end

        % Menu selected function: SequenceTextEditor
        function SequenceTextEditor_Callback(app, event)
            % hObject    handle to SequenceTextEditor (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
               prompt={'FASTA format:'};
               def={''};
               dlgTitle='Sequences Text Editor';
               lineNo=6;
               AddOpts.Resize='off';
               AddOpts.WindowStyle='modal';
               AddOpts.Interpreter='none';
               answer=inputdlg(prompt,dlgTitle,lineNo,def,AddOpts);
            
             if ~(isempty(answer)),
                  seq=cellstr(answer{1});
                  n=length(seq);
             if (n>=2&&~isempty(seq(2)))
                   tempf = tempname;
                   [fid,Msg] = fopen(tempf,'wt');
                   if fid == -1, error(Msg); end
                   for k=1:n
                       fprintf(fid,'%s\n',char(seq(k)));
                   end
                   fclose(fid);
               app.aln=readfasta(tempf);
               SetMenuStatus(handles);
            
                n=size(app.aln.seq,1);
            
                app.aln.locus=ones(n,1);
            	app.aln.population=ones(n,1);
            	app.aln.count=ones(n,1);
                app.aln
            else
                helpdlg('Sorry, not valid sequences.')
            end
            end
            
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: SlidingWinFuDStar
        function SlidingWinFuDStar_Callback(app, event)
            % hObject    handle to SlidingWinFuDStar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            [winsiz,stpsiz] = i_getwindowsizestep;
            if ~(winsiz>0 & stpsiz>0)
               return;
            else
            	try
            	    h=figure;
            	    subplot(2,1,1)
            	    switchoutput=0;
            	    slidingfun(@fuli93dsfs_test,app.aln,winsiz,stpsiz,switchoutput);
            	    ylabel('Fu and Li''s D*')
            	    subplot(2,1,2)
            	    switchoutput=1;
            	    slidingfun(@fuli93dsfs_test,app.aln,winsiz,stpsiz,switchoutput);
            	    ylabel('Fu and Li''s F*')
                catch
                    close(h);
                    errordlg(lasterr)
            	    %warndlg('Inappropriate WINSIZE or STEP.')
            	end
            end
        end

        % Menu selected function: SlidingWinFuFs
        function SlidingWinFuFs_Callback(app, event)
            % hObject    handle to SlidingWinFuFs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [winsiz,stpsiz] = i_getwindowsizestep;
            if ~(winsiz>0 && stpsiz>0)
               return;
            else
            	try
            	    h=figure;
            	    slidingfun(@fu97fs,app.aln,winsiz,stpsiz);
                    ylabel('Fu''s Fs')
                catch
                    close(h);
                    errordlg(lasterr)
            	    %warndlg('Inappropriate WINSIZE or STEP.')
            	end
            end
        end

        % Menu selected function: SlidingWinTajimaD
        function SlidingWinTajimaD_Callback(app, event)
            % hObject    handle to SlidingWinTajimaD (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            [winsiz,stpsiz] = i_getwindowsizestep;
            if ~(winsiz>0 && stpsiz>0)
               return;
            else
            	try
            	    h=figure;
            	    slidingfun(@tajima89d_test,app.aln,winsiz,stpsiz);
                    ylabel('Tajima''s D')
                catch
                    close(h);
                    errordlg(lasterr)
            	    %warndlg('Inappropriate WINSIZE or STEP.')
            	end
            end
        end

        % Menu selected function: SmithEyreWalker02
        function SmithEyreWalker02_Callback(app, event)
            % hObject    handle to SmithEyreWalker02 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
            [~,data]=line4sewfww(app.aln);
            D=sewfww_sampledata(data);
            if isempty(D)
                return;
            else
                sew=sewfww(D);
                i_dispheader('alpha, Smith and Eyre-Walker (2002)')
                fprintf('alpha=%.3f\n',sew);
                i_dispfooter
            end
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: Tajima89Test
        function Tajima89Test_Callback(app, event)
            % hObject    handle to Tajima89Test (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
               tajima89d_test(app.aln);
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: TestOfIndependence2x2Table
        function TestOfIndependence2x2Table_Callback(app, event)
            % hObject    handle to TestOfIndependence2x2Table (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                ds=189;
                ps=113;
                dr=42;
                pr=10;
            
                gcbox=dprstable;
            	handles = guihandles(gcbox);
            	set(app.ds, 'String', num2str(ds));
            	set(app.ps, 'String', num2str(ps));
            	set(app.dr, 'String', num2str(dr));
            	set(app.pr, 'String', num2str(pr));
            	set(app.dsps,'String',num2str(ds+ps));
            	set(app.drpr,'String',num2str(dr+pr));
            	set(app.dsdr,'String',num2str(ds+dr));
            	set(app.pspr,'String',num2str(ps+pr));
            	set(app.total,'String',num2str(ps+pr+ds+dr));
        end

        % Menu selected function: UnfoldedSiteFrequencySpectrum
        function UnfoldedSiteFrequencySpectrum_Callback(app, event)
            % hObject    handle to SiteFrequencySpectrum (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
                  str=app.aln.seqnames;
                  [s,v] = listdlg('PromptString','Select an ancestral reference:',...
                                  'SelectionMode','single',...
                                  'ListString',str);
            if v>0
                fprintf('NOTE: Sequence %d, ''%s'', was used as ancestral\nreference to count external-mutations.\n',...
                        s,deblank(str{s}));
                try
                sfs(app.aln,s);
                f=sfs(app.aln,s);
                figure;
                bar(f)
            catch ME
                errordlg(ME.message)
            end
            end
        end

        % Menu selected function: ViewSequencesInfo
        function ViewSequencesInfo_Callback(app, event)
            % hObject    handle to ViewSequencesInfo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.aln
            try
               pge_viewdata(app.aln)
            catch ME
               errordlg(ME)
            end
        end

        % Menu selected function: ViewSequences
        function ViewSequences_Callback(app, event)
            % hObject    handle to ViewSequences (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            viewseq(app.aln);
        end

        % Menu selected function: WallBQ
        function WallBQ_Callback(app, event)
            % hObject    handle to WallBQ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ;
            try
                wall99bq(app.aln);
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: Watterson78F
        function Watterson78F_Callback(app, event)
            % hObject    handle to Watterson78W (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
                watterson78f(app.aln);
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: WorkOnParsimonyInformativeSites
        function WorkOnParsimonyInformativeSites_Callback(app, event)
            % hObject    handle to WorkOnParsimonyInformativeSites (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
             app.aln_ori
            try
            app.aln_ori=app.aln;
            [app.aln,informativesites]=extractinformativesites(app.aln);
            if isempty(informativesites)
                warndlg('No informative site.')
                app.aln=app.aln_ori;
                app.aln_ori=[];
            else
                disp('Working on Parsimony informative sites only!');
            end
            SetMenuStatus(handles);
            catch ME
                errordlg(ME.message)
            end
        end

        % Menu selected function: WorkOnPloymorphicSites
        function WorkOnPloymorphicSites_Callback(app, event)
            % hObject    handle to WorkOnPloymorphicSites (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
             app.aln_ori
            try
            app.aln_ori=app.aln;
            app.aln=extractsegregatingsites(app.aln);
            if isempty(app.aln.seq)
                warndlg('No segregating site.')
                app.aln=app.aln_ori;
                app.aln_ori=[];
            else
                disp('Working on ploymorphic (segregating) sites only!');
            end
            SetMenuStatus(handles);
            catch ME
                errordlg(ME.message)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PGEToolboxGUI = uifigure;
            app.PGEToolboxGUI.Name = 'Population Genetics & Evolution Toolbox - [Sequence]';
            app.File = uimenu(app.PGEToolboxGUI);
            app.File.Text = '&File';
            app.OpenDataFile = uimenu(app.File);
            app.OpenDataFile.MenuSelectedFcn = createCallbackFcn(app, @OpenDataFile_Callback, true);
            app.OpenDataFile.Text = 'Open Data &File...';
            app.CloseDataFile = uimenu(app.File);
            app.CloseDataFile.MenuSelectedFcn = createCallbackFcn(app, @CloseDataFile_Callback, true);
            app.CloseDataFile.Text = '&Close Data File';
            app.SaveExportDataAs = uimenu(app.File);
            app.SaveExportDataAs.Separator = 'on';
            app.SaveExportDataAs.Text = 'Save / &Export Data As...';
            app.SaveFASTAFile = uimenu(app.SaveExportDataAs);
            app.SaveFASTAFile.MenuSelectedFcn = createCallbackFcn(app, @SaveFASTAFile_Callback, true);
            app.SaveFASTAFile.Text = '&FASTA Format File';
            app.SavePhylipFile = uimenu(app.SaveExportDataAs);
            app.SavePhylipFile.MenuSelectedFcn = createCallbackFcn(app, @SavePhylipFile_Callback, true);
            app.SavePhylipFile.Text = '&PHYLIP Format File';
            app.SavePAMLFile = uimenu(app.SaveExportDataAs);
            app.SavePAMLFile.Text = '&PAML Format File';
            app.SaveMatFile = uimenu(app.SaveExportDataAs);
            app.SaveMatFile.MenuSelectedFcn = createCallbackFcn(app, @SaveMatFile_Callback, true);
            app.SaveMatFile.Text = '&MAT Format File';
            app.AssignSequencesInWorkspace = uimenu(app.SaveExportDataAs);
            app.AssignSequencesInWorkspace.MenuSelectedFcn = createCallbackFcn(app, @AssignSequencesInWorkspace_Callback, true);
            app.AssignSequencesInWorkspace.Separator = 'on';
            app.AssignSequencesInWorkspace.Text = '&Workspace Variable (ALN)';
            app.SequenceTextEditor = uimenu(app.File);
            app.SequenceTextEditor.MenuSelectedFcn = createCallbackFcn(app, @SequenceTextEditor_Callback, true);
            app.SequenceTextEditor.Separator = 'on';
            app.SequenceTextEditor.Text = 'Sequence Text Editor...';
            app.GenerateRandomSequences = uimenu(app.File);
            app.GenerateRandomSequences.MenuSelectedFcn = createCallbackFcn(app, @GenerateRandomSequences_Callback, true);
            app.GenerateRandomSequences.Text = 'Generate Random Sequences...';
            app.Exit = uimenu(app.File);
            app.Exit.MenuSelectedFcn = createCallbackFcn(app, @Exit_Callback, true);
            app.Exit.Separator = 'on';
            app.Exit.Text = 'E&xit';
            app.Data = uimenu(app.PGEToolboxGUI);
            app.Data.Text = '&Data';
            app.ViewSequences = uimenu(app.Data);
            app.ViewSequences.MenuSelectedFcn = createCallbackFcn(app, @ViewSequences_Callback, true);
            app.ViewSequences.Text = '&View Sequences';
            app.ViewSequencesInfo = uimenu(app.Data);
            app.ViewSequencesInfo.MenuSelectedFcn = createCallbackFcn(app, @ViewSequencesInfo_Callback, true);
            app.ViewSequencesInfo.Text = 'View Sequences &Info...';
            app.AssignPopulation = uimenu(app.Data);
            app.AssignPopulation.MenuSelectedFcn = createCallbackFcn(app, @AssignPopulation_Callback, true);
            app.AssignPopulation.Separator = 'on';
            app.AssignPopulation.Text = 'Assign Population...';
            app.AssignLocus = uimenu(app.Data);
            app.AssignLocus.MenuSelectedFcn = createCallbackFcn(app, @AssignLocus_Callback, true);
            app.AssignLocus.Text = 'Assign Locus...';
            app.RemoveGapsInSequences = uimenu(app.Data);
            app.RemoveGapsInSequences.MenuSelectedFcn = createCallbackFcn(app, @RemoveGapsInSequences_Callback, true);
            app.RemoveGapsInSequences.Separator = 'on';
            app.RemoveGapsInSequences.Text = '&Remove Gaps in Sequences';
            app.WorkOnPloymorphicSites = uimenu(app.Data);
            app.WorkOnPloymorphicSites.MenuSelectedFcn = createCallbackFcn(app, @WorkOnPloymorphicSites_Callback, true);
            app.WorkOnPloymorphicSites.Text = 'Ploymorphic (Segregating) Sites Only';
            app.WorkOnParsimonyInformativeSites = uimenu(app.Data);
            app.WorkOnParsimonyInformativeSites.MenuSelectedFcn = createCallbackFcn(app, @WorkOnParsimonyInformativeSites_Callback, true);
            app.WorkOnParsimonyInformativeSites.Text = 'Parsimony Informative Sites Only';
            app.IncludeExcludeSequences = uimenu(app.Data);
            app.IncludeExcludeSequences.MenuSelectedFcn = createCallbackFcn(app, @IncludeExcludeSequences_Callback, true);
            app.IncludeExcludeSequences.Separator = 'on';
            app.IncludeExcludeSequences.Text = 'Include / &Exclude Sequences...';
            app.IncludeExcludeSites = uimenu(app.Data);
            app.IncludeExcludeSites.MenuSelectedFcn = createCallbackFcn(app, @IncludeExcludeSites_Callback, true);
            app.IncludeExcludeSites.Text = 'Include / Exclude Sites...';
            app.RestoreSequences = uimenu(app.Data);
            app.RestoreSequences.MenuSelectedFcn = createCallbackFcn(app, @RestoreSequences_Callback, true);
            app.RestoreSequences.Text = 'Restore Sequences';
            app.Analysis = uimenu(app.PGEToolboxGUI);
            app.Analysis.Text = '&Analysis';
            app.ReportPolymorphicSites = uimenu(app.Analysis);
            app.ReportPolymorphicSites.MenuSelectedFcn = createCallbackFcn(app, @ReportPolymorphicSites_Callback, true);
            app.ReportPolymorphicSites.Text = '&Polymorphic Sites';
            app.NucleotideDiversity = uimenu(app.Analysis);
            app.NucleotideDiversity.MenuSelectedFcn = createCallbackFcn(app, @NucleotideDiversity_Callback, true);
            app.NucleotideDiversity.Text = '&Nucleotide Diversity (Pi)';
            app.EstimateTheta = uimenu(app.Analysis);
            app.EstimateTheta.MenuSelectedFcn = createCallbackFcn(app, @EstimateTheta_Callback, true);
            app.EstimateTheta.Text = '&Estimate Theta (4Nµ)';
            app.SiteFreqSpectrum = uimenu(app.Analysis);
            app.SiteFreqSpectrum.Text = 'Site-Freq Spectrum (sfs)';
            app.FoldedSiteFrequencySpectrum = uimenu(app.SiteFreqSpectrum);
            app.FoldedSiteFrequencySpectrum.MenuSelectedFcn = createCallbackFcn(app, @FoldedSiteFrequencySpectrum_Callback, true);
            app.FoldedSiteFrequencySpectrum.Text = 'Folded';
            app.UnfoldedSiteFrequencySpectrum = uimenu(app.SiteFreqSpectrum);
            app.UnfoldedSiteFrequencySpectrum.MenuSelectedFcn = createCallbackFcn(app, @UnfoldedSiteFrequencySpectrum_Callback, true);
            app.UnfoldedSiteFrequencySpectrum.Text = 'Unfolded...';
            app.Tajima89Test = uimenu(app.Analysis);
            app.Tajima89Test.MenuSelectedFcn = createCallbackFcn(app, @Tajima89Test_Callback, true);
            app.Tajima89Test.Separator = 'on';
            app.Tajima89Test.Text = '&Tajima''s Test (D)';
            app.FuLi93Tests = uimenu(app.Analysis);
            app.FuLi93Tests.MenuSelectedFcn = createCallbackFcn(app, @FuLi93Tests_Callback, true);
            app.FuLi93Tests.Text = '&Fu && Li''s Tests (D*, F*)';
            app.FayWusTestH = uimenu(app.Analysis);
            app.FayWusTestH.MenuSelectedFcn = createCallbackFcn(app, @FayWusTestH_Callback, true);
            app.FayWusTestH.Text = 'Fay && Wu''s Test (H)';
            app.SlidingWindowsAnalysis = uimenu(app.Analysis);
            app.SlidingWindowsAnalysis.Separator = 'on';
            app.SlidingWindowsAnalysis.Text = 'Sliding Windows Analysis';
            app.SlidingWinTajimaD = uimenu(app.SlidingWindowsAnalysis);
            app.SlidingWinTajimaD.MenuSelectedFcn = createCallbackFcn(app, @SlidingWinTajimaD_Callback, true);
            app.SlidingWinTajimaD.Text = 'Tajima''s D';
            app.SlidingWinFuDStar = uimenu(app.SlidingWindowsAnalysis);
            app.SlidingWinFuDStar.MenuSelectedFcn = createCallbackFcn(app, @SlidingWinFuDStar_Callback, true);
            app.SlidingWinFuDStar.Text = 'Fu''s D* && F*';
            app.SlidingWinFuFs = uimenu(app.SlidingWindowsAnalysis);
            app.SlidingWinFuFs.MenuSelectedFcn = createCallbackFcn(app, @SlidingWinFuFs_Callback, true);
            app.SlidingWinFuFs.Text = 'Fu''s Fs';
            app.HaplotypeDiversity = uimenu(app.Analysis);
            app.HaplotypeDiversity.MenuSelectedFcn = createCallbackFcn(app, @HaplotypeDiversity_Callback, true);
            app.HaplotypeDiversity.Separator = 'on';
            app.HaplotypeDiversity.Text = 'Haplotype Diversity (Hd)';
            app.Watterson78F = uimenu(app.Analysis);
            app.Watterson78F.MenuSelectedFcn = createCallbackFcn(app, @Watterson78F_Callback, true);
            app.Watterson78F.Text = 'Ewens-&Watterson''s Homozygosity Test';
            app.FuFsTest = uimenu(app.Analysis);
            app.FuFsTest.MenuSelectedFcn = createCallbackFcn(app, @FuFsTest_Callback, true);
            app.FuFsTest.Text = 'Fu''s (Fs) && Strobeck''s (S) Tests';
            app.R2Test = uimenu(app.Analysis);
            app.R2Test.MenuSelectedFcn = createCallbackFcn(app, @R2Test_Callback, true);
            app.R2Test.Separator = 'on';
            app.R2Test.Text = '&Ramos-Onsins && Rozas Test (R2)';
            app.McDKTestCMD = uimenu(app.Analysis);
            app.McDKTestCMD.MenuSelectedFcn = createCallbackFcn(app, @McDKTestCMD_Callback, true);
            app.McDKTestCMD.Separator = 'on';
            app.McDKTestCMD.Text = '&McDonald-Kreitman Test (command line)';
            app.McDKTestGUI = uimenu(app.Analysis);
            app.McDKTestGUI.MenuSelectedFcn = createCallbackFcn(app, @McDKTestGUI_Callback, true);
            app.McDKTestGUI.Text = 'McDonald-Kreitman Test (GUI)...';
            app.HKATest = uimenu(app.Analysis);
            app.HKATest.Separator = 'on';
            app.HKATest.Text = '&Hudson, Kreitman and Aguadé Test';
            app.Recombination = uimenu(app.Analysis);
            app.Recombination.Separator = 'on';
            app.Recombination.Text = 'Recombination';
            app.FourGameteTest = uimenu(app.Recombination);
            app.FourGameteTest.MenuSelectedFcn = createCallbackFcn(app, @FourGameteTest_Callback, true);
            app.FourGameteTest.Text = 'Four-gamete Test (FGT)';
            app.LinkageDisequilibrium = uimenu(app.Analysis);
            app.LinkageDisequilibrium.Text = '&Linkage Disequilibrium';
            app.LDOverview = uimenu(app.LinkageDisequilibrium);
            app.LDOverview.MenuSelectedFcn = createCallbackFcn(app, @LDOverview_Callback, true);
            app.LDOverview.Text = 'D, D'' and R^2';
            app.LDDistancePlot = uimenu(app.LinkageDisequilibrium);
            app.LDDistancePlot.Separator = 'on';
            app.LDDistancePlot.Text = 'LD distance plot';
            app.LDDistancePlotD = uimenu(app.LDDistancePlot);
            app.LDDistancePlotD.MenuSelectedFcn = createCallbackFcn(app, @LDDistancePlotD_Callback, true);
            app.LDDistancePlotD.Text = 'D';
            app.LDDistancePlotDAbs = uimenu(app.LDDistancePlot);
            app.LDDistancePlotDAbs.MenuSelectedFcn = createCallbackFcn(app, @LDDistancePlotDAbs_Callback, true);
            app.LDDistancePlotDAbs.Text = '|D|';
            app.LDDistancePlotDPrime = uimenu(app.LDDistancePlot);
            app.LDDistancePlotDPrime.MenuSelectedFcn = createCallbackFcn(app, @LDDistancePlotDPrime_Callback, true);
            app.LDDistancePlotDPrime.Text = 'D''';
            app.LDDistancePlotDPrimeAbs = uimenu(app.LDDistancePlot);
            app.LDDistancePlotDPrimeAbs.MenuSelectedFcn = createCallbackFcn(app, @LDDistancePlotDPrimeAbs_Callback, true);
            app.LDDistancePlotDPrimeAbs.Text = '|D''|';
            app.LDDistancePlotR = uimenu(app.LDDistancePlot);
            app.LDDistancePlotR.MenuSelectedFcn = createCallbackFcn(app, @LDDistancePlotR_Callback, true);
            app.LDDistancePlotR.Text = 'R';
            app.LDDistancePlotR2 = uimenu(app.LDDistancePlot);
            app.LDDistancePlotR2.MenuSelectedFcn = createCallbackFcn(app, @LDDistancePlotR2_Callback, true);
            app.LDDistancePlotR2.Text = 'R^2';
            app.LDMatrixPlot = uimenu(app.LinkageDisequilibrium);
            app.LDMatrixPlot.Text = 'LD matrix plot';
            app.LDMatrixPlotD = uimenu(app.LDMatrixPlot);
            app.LDMatrixPlotD.MenuSelectedFcn = createCallbackFcn(app, @LDMatrixPlotD_Callback, true);
            app.LDMatrixPlotD.Text = 'D';
            app.LDMatrixPlotDAbs = uimenu(app.LDMatrixPlot);
            app.LDMatrixPlotDAbs.MenuSelectedFcn = createCallbackFcn(app, @LDMatrixPlotDAbs_Callback, true);
            app.LDMatrixPlotDAbs.Text = '|D|';
            app.LDMatrixPlotDPrime = uimenu(app.LDMatrixPlot);
            app.LDMatrixPlotDPrime.MenuSelectedFcn = createCallbackFcn(app, @LDMatrixPlotDPrime_Callback, true);
            app.LDMatrixPlotDPrime.Text = 'D''';
            app.LDMatrixPlotDPrimeAbs = uimenu(app.LDMatrixPlot);
            app.LDMatrixPlotDPrimeAbs.MenuSelectedFcn = createCallbackFcn(app, @LDMatrixPlotDPrimeAbs_Callback, true);
            app.LDMatrixPlotDPrimeAbs.Text = '|D''|';
            app.LDMatrixPlotR = uimenu(app.LDMatrixPlot);
            app.LDMatrixPlotR.MenuSelectedFcn = createCallbackFcn(app, @LDMatrixPlotR_Callback, true);
            app.LDMatrixPlotR.Text = 'R';
            app.LDMatrixPlotR2 = uimenu(app.LDMatrixPlot);
            app.LDMatrixPlotR2.MenuSelectedFcn = createCallbackFcn(app, @LDMatrixPlotR2_Callback, true);
            app.LDMatrixPlotR2.Text = 'R^2';
            app.WallBQ = uimenu(app.Analysis);
            app.WallBQ.MenuSelectedFcn = createCallbackFcn(app, @WallBQ_Callback, true);
            app.WallBQ.Text = 'Wall''s Tests (B, Q)';
            app.Tools = uimenu(app.PGEToolboxGUI);
            app.Tools.Text = '&Tools';
            app.CoalescentSimulations = uimenu(app.Tools);
            app.CoalescentSimulations.MenuSelectedFcn = createCallbackFcn(app, @CoalescentSimulations_Callback, true);
            app.CoalescentSimulations.Text = '&Coalescent Simulations...';
            app.TestOfIndependence2x2Table = uimenu(app.Tools);
            app.TestOfIndependence2x2Table.MenuSelectedFcn = createCallbackFcn(app, @TestOfIndependence2x2Table_Callback, true);
            app.TestOfIndependence2x2Table.Text = 'Test of &Independence: 2 x 2 Table...';
            app.RatioOfAdaptiveNonsynSub = uimenu(app.Tools);
            app.RatioOfAdaptiveNonsynSub.Separator = 'on';
            app.RatioOfAdaptiveNonsynSub.Text = 'Ratio of Adaptive Nonsyn. Sub. (alpha)';
            app.FayWycoffWu01 = uimenu(app.RatioOfAdaptiveNonsynSub);
            app.FayWycoffWu01.MenuSelectedFcn = createCallbackFcn(app, @FayWycoffWu01_Callback, true);
            app.FayWycoffWu01.Text = 'Fay, Wycoff && Wu (01)';
            app.SmithEyreWalker02 = uimenu(app.RatioOfAdaptiveNonsynSub);
            app.SmithEyreWalker02.MenuSelectedFcn = createCallbackFcn(app, @SmithEyreWalker02_Callback, true);
            app.SmithEyreWalker02.Text = 'Smith && Eyre-Walker (02)';
            app.RatioOfAdaptiveNonsynSubFWW = uimenu(app.RatioOfAdaptiveNonsynSub);
            app.RatioOfAdaptiveNonsynSubFWW.MenuSelectedFcn = createCallbackFcn(app, @RatioOfAdaptiveNonsynSubFWW_Callback, true);
            app.RatioOfAdaptiveNonsynSubFWW.Separator = 'on';
            app.RatioOfAdaptiveNonsynSubFWW.Text = 'Fay, Wycoff && Wu (01), Sample Data';
            app.RatioOfAdaptiveNonsynSubSEW = uimenu(app.RatioOfAdaptiveNonsynSub);
            app.RatioOfAdaptiveNonsynSubSEW.MenuSelectedFcn = createCallbackFcn(app, @RatioOfAdaptiveNonsynSubSEW_Callback, true);
            app.RatioOfAdaptiveNonsynSubSEW.Text = 'Smith && Eyre-Walker (02), Sample Data';
            app.MKRPFTest = uimenu(app.Tools);
            app.MKRPFTest.Text = 'MKRPF Test';
            app.MKRPFTestWriteInputLine = uimenu(app.MKRPFTest);
            app.MKRPFTestWriteInputLine.Text = 'Write Input Line';
            app.MKRPFTestExampleInput = uimenu(app.MKRPFTest);
            app.MKRPFTestExampleInput.Separator = 'on';
            app.MKRPFTestExampleInput.Text = 'Example Input...';
            app.SNPTool = uimenu(app.Tools);
            app.SNPTool.MenuSelectedFcn = createCallbackFcn(app, @SNPTool_Callback, true);
            app.SNPTool.Separator = 'on';
            app.SNPTool.Text = '&SNP Tool...';
            app.Help = uimenu(app.PGEToolboxGUI);
            app.Help.Text = '&Help';
            app.HelpContents = uimenu(app.Help);
            app.HelpContents.MenuSelectedFcn = createCallbackFcn(app, @HelpContents_Callback, true);
            app.HelpContents.Text = '&Help Contents';
            app.Demos = uimenu(app.Help);
            app.Demos.MenuSelectedFcn = createCallbackFcn(app, @Demos_Callback, true);
            app.Demos.Text = '&Demos...';
            app.HelpWebSite = uimenu(app.Help);
            app.HelpWebSite.MenuSelectedFcn = createCallbackFcn(app, @HelpWebSite_Callback, true);
            app.HelpWebSite.Separator = 'on';
            app.HelpWebSite.Text = '&PGEToolbox Website';
            app.CheckLatestVersion = uimenu(app.Help);
            app.CheckLatestVersion.MenuSelectedFcn = createCallbackFcn(app, @CheckLatestVersion_Callback, true);
            app.CheckLatestVersion.Text = '&Check Latest Version';
            app.Citation = uimenu(app.Help);
            app.Citation.MenuSelectedFcn = createCallbackFcn(app, @Citation_Callback, true);
            app.Citation.Text = 'C&itation';
            app.AboutPGEToolbox = uimenu(app.Help);
            app.AboutPGEToolbox.MenuSelectedFcn = createCallbackFcn(app, @AboutPGEToolbox_Callback, true);
            app.AboutPGEToolbox.Separator = 'on';
            app.AboutPGEToolbox.Text = '&About...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = seqtool_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PGEToolboxGUI)

            % Execute the startup function
            runStartupFcn(app, @seqtool_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PGEToolboxGUI)
        end
    end
end